classdef sinewave_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SineWaveGenerator         matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        PeriodEditFieldLabel      matlab.ui.control.Label
        PeriodEditField           matlab.ui.control.NumericEditField
        PhaseEditFieldLabel       matlab.ui.control.Label
        PhaseEditField            matlab.ui.control.NumericEditField
        PlotButton                matlab.ui.control.Button
        MATLABBasicsWebinarLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            a = app.AmplitudeEditField.Value;
            period = app.PeriodEditField.Value;
            phase = app.PhaseEditField.Value;
            
            x = linspace(-1*period,period);
            y = a*sin(x + phase);
            plot(app.UIAxes,y); 
            title(app.UIAxes, "y = " + a + "sin(x + " + phase + ")")
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            legend(app.UIAxes, 'Amplitude')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SineWaveGenerator = uifigure;
            app.SineWaveGenerator.Name = 'Sine Wave Generator';
            app.UIAxes = uiaxes(app.SineWaveGenerator);
            title(app.UIAxes, 'Sinusoidal Waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmplitudeEditFieldLabel = uilabel(app.SineWaveGenerator);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SineWaveGenerator, 'numeric');
            app.PeriodEditFieldLabel = uilabel(app.SineWaveGenerator);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.SineWaveGenerator, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.SineWaveGenerator);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.SineWaveGenerator, 'numeric');
            app.PlotButton = uibutton(app.SineWaveGenerator, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MATLABBasicsWebinarLabel = uilabel(app.SineWaveGenerator);
            app.MATLABBasicsWebinarLabel.Text = '© MATLAB Basics Webinar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sinewave_plot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SineWaveGenerator)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SineWaveGenerator)
        end
    end
end